classdef Exp_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        AdviceTextAreaLabel      matlab.ui.control.Label
        AdviceTextArea           matlab.ui.control.TextArea
        ScoreEditFieldLabel      matlab.ui.control.Label
        ScoreEditField           matlab.ui.control.NumericEditField
        RightPanel               matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
        SelectnodeDropDownLabel  matlab.ui.control.Label
        SelectnodeDropDown       matlab.ui.control.DropDown
        StartSwitchLabel         matlab.ui.control.Label
        StartSwitch              matlab.ui.control.ToggleSwitch
        DemonstrateButton        matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        node_1 % Description
        node_2 % Description
        node_3 % Description
        node_4 % Description
        node_5 % Description
        node_6 % Description
        node_7 % Description
        node_8 % Description
        node_9 % Description
        node_10 % Description
        node_11 % Description
        node_12 % Description
        temp % transfer node location from dropdown to demo button
        temp2 % degree
        temp1
        temp3
        temp4
        temp0
        data = [] % data
        slope % slope
        a % Description
        a1 % Description
        a2 % Description
        a3 % Description
        rightNow % Description
        howlong % Description
        error
        record = [] % inport_record
        score_record = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            gridpoints = [[0,0];[0.3,0.3];[0.3,0.4];[0.3,0.5];[0.4,0.3];[0.4,0.4];[0.4,0.5];[0.5,0.3];[0.5,0.4];[0.5,0.5];[0.6,0.3];[0.6,0.4];[0.6,0.5];];
            scatter(app.UIAxes,gridpoints(:,1),gridpoints(:,2),'filled')
            hold(app.UIAxes,"on")


        end

        % Value changed function: SelectnodeDropDown
        function SelectnodeDropDownValueChanged(app, event)
            value = app.SelectnodeDropDown.Value;
            
            if (strcmp(value,'Node gg')==1)
                app.temp = [0.3,0.3];
                app.temp0 = linspace(0, 0.3,100);
                app.slope = 3/3;
                
            elseif (strcmp(value,'Node 1')==1)
                app.temp = [0.3,0.3];
                app.temp0 = linspace(0, 0.3,100);
                app.slope = 3/3;

                
            elseif (strcmp(value,'Node 2')==1)
                app.temp = [0.3,0.4];
                app.temp0 = linspace(0, 0.3,100);
                app.slope = 4/3;
                
            elseif (strcmp(value,'Node 3')==1)
                app.temp = [0.3,0.5];
                app.temp0 = linspace(0, 0.3,100);
                app.slope = 5/3;
                
            elseif (strcmp(value,'Node 4')==1)
                app.temp = [0.4,0.3];
                app.temp0 = linspace(0, 0.4,100);
                app.slope = 3/4;
            elseif (strcmp(value,'Node 5')==1)
                app.temp = [0.4,0.4];
                app.temp0 = linspace(0, 0.4,100);
                app.slope = 4/4;
            elseif (strcmp(value,'Node 6')==1)
                app.temp = [0.4,0.5];
                app.temp0 = linspace(0, 0.4,100);
                app.slope = 5/4;
            elseif (strcmp(value,'Node 7')==1)
                app.temp = [0.5,0.3];
                app.temp0 = linspace(0, 0.5,100);
                app.slope = 3/5;
            elseif (strcmp(value,'Node 8')==1)
                app.temp = [0.5,0.4];
                app.temp0 = linspace(0, 0.5,100);
                app.slope = 4/5;
            elseif (strcmp(value,'Node 9')==1)
                app.temp = [0.5,0.5];
                app.temp0 = linspace(0, 0.5,100);
                app.slope = 5/5;
            elseif (strcmp(value,'Node 10')==1)
                app.temp = [0.6,0.3];
                app.temp0 = linspace(0, 0.6,100);
                app.slope = 3/6;
            elseif (strcmp(value,'Node 11')==1)
                app.temp = [0.6,0.4];
                app.temp0 = linspace(0, 0.6,100);
                app.slope = 4/6;
            elseif (strcmp(value,'Node 12')==1)
                app.temp = [0.6,0.5];
                app.temp0 = linspace(0, 0.6,100);
                app.slope = 5/6;

            end


        end

        % Button pushed function: DemonstrateButton
        function DemonstrateButtonPushed(app, event)
%             disp(app.data)
            a_regulation = 0.48;
            b_regulation = 0.363;
            c_regulation = 0.29;
 
            for d = 1:length(app.temp0)
                y = app.temp0(:,d)*app.slope;
                app.data = [[d,app.temp0(:,d),y];app.data]; %1
                
                
            end
            scatter(app.UIAxes,app.data(:,2),app.data(:,3))
%             experiment_data = app.data;
%             experiment_data = uiputfile(experiment_data.mat);
%             assignin("base","experiment_data",app.data);
            data_x = app.data(:,2);
            data_y = app.data(:,3);
            size_data_x = size(app.data,1);
            [U,S,V] = svd(data_x(1:size_data_x),'econ');
            
            app.a = V*inv(S)*U'*data_y(1:size_data_x);
            
            app.temp1 = linspace(0, 0.3,100);
            app.temp2 = linspace(0, 0.4,100);
            app.temp3 = linspace(0, 0.5,100);
            app.temp4 = linspace(0, 0.6,100);
            
            a_1_1 = app.a*(1/app.a);
            a_1_2 = app.a*(1/app.a+a_regulation);
            a_1_3 = app.a*(1/app.a+2*a_regulation);
            
            a_2_1 = app.a*(0.75/app.a);
            a_2_2 = app.a*(0.75/app.a+b_regulation);
            a_2_3 = app.a*(0.75/app.a+2*b_regulation);
            
            a_3_1 = app.a*(0.6/app.a);
            a_3_2 = app.a*(0.6/app.a+c_regulation);
            a_3_3 = app.a*(0.6/app.a+2*c_regulation);
            
            a_4_1 = app.a*0.7262;
            a_4_2 = app.a*0.9683;
            a_4_3 = app.a*1.2104;
            
            plot(app.UIAxes,app.temp1,app.temp1*a_1_1,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp1,app.temp1*a_1_2,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp1,app.temp1*a_1_3,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp2,app.temp2*a_2_1,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp2,app.temp2*a_2_2,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp2,app.temp2*a_2_3,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp3,app.temp3*a_3_1,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp3,app.temp3*a_3_2,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp3,app.temp3*a_3_3,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp4,app.temp4*a_4_1,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp4,app.temp4*a_4_2,'k','LineWidth',2)
            
            plot(app.UIAxes,app.temp4,app.temp4*a_4_3,'k','LineWidth',2)
            
            error_1 = abs(sum(app.temp1*1 - app.temp1*a_1_1));
            error_2 = abs(sum(app.temp1*4/3 - app.temp1*a_1_2));
            error_3 = abs(sum(app.temp1*5/3 - app.temp1*a_1_3));
            
            error_4 = abs(sum(app.temp2*3/4 - app.temp2*a_2_1));
            error_5 = abs(sum(app.temp2*1 - app.temp2*a_2_2));
            error_6 = abs(sum(app.temp2*5/4 - app.temp2*a_2_3));
            
            error_7 = abs(sum(app.temp3*3/5 - app.temp3*a_3_1));
            error_8 = abs(sum(app.temp3*4/5 - app.temp3*a_3_2));
            error_9 = abs(sum(app.temp3*5/5 - app.temp3*a_3_3));
            
            error_10 = abs(sum(app.temp4*3/6 - app.temp4*a_4_1));
            error_11 = abs(sum(app.temp4*4/6 - app.temp4*a_4_2));
            error_12 = abs(sum(app.temp4*5/6 - app.temp4*a_4_3));
            app.error = error_1 + error_2 + error_3 + error_4 + error_5 + error_6 + error_7 + error_8 + error_9 + error_10 + error_11 + error_12;
            
            sec = (seconds(datetime("now")-app.rightNow))/100;
            app.ScoreEditField.Value = 100 - (app.error+sec);


            app.score_record = [[app.ScoreEditField.Value];app.score_record];
            
            
            assignin("base","experiment_score",app.score_record);
            assignin("base","experiment_data",app.data);
            assignin("base","time",sec);
            
            Node_select = app.SelectnodeDropDown.Value;
            if (strcmp(Node_select,'Node 1')==1)
                
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 2')==1)
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 3')==1)
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 4')==1)
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 5')==1)
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 6')==1)
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 7')==1)
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 8')==1)
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 9')==1)
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 10')==1)
                app.record = [app.record, Node_select];
                
            elseif (strcmp(Node_select,'Node 11')==1)
                app.record = [app.record, Node_select];

            elseif (strcmp(Node_select,'Node 12')==1)
                app.record = [app.record, Node_select];
                    
            end
            
            if (strcmp(app.record,'Node 1')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 1 is a great choice! Base on algorithm's calculation, the next best demo point will be Node 7/Node 11 to reach HIGHEST scores ";
            elseif (strcmp(app.record,'Node 2')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 2 is a great choice! Base on algorithm's calculation, the next best demo point will be Node 10/Node 4 to reach HIGHEST overall scores, second best demo point would be Node 7^^ GL! "; 
            
            elseif (strcmp(app.record,'Node 3')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 3 is a great choice! Base on algorithm's calculation, the next best demo point will be Node 10/Node 7 to reach HIGHEST overall scores."; 
            
            elseif (strcmp(app.record,'Node 4')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 4 is a great choice! Base on algorithm's calculation, to reach HIGHEST overall scores, the next best demo point would be Node 10/Node 7 ^^ GL! "; 
            
            elseif (strcmp(app.record,'Node 5')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 5 is the best choice in first demo within 12 points! Base on algorithm's calculation, the next best demo point will be Node 7 to reach HIGHEST overall scores, the second best demo point would be Node 8 ^^ GL! "; 
            
            elseif (strcmp(app.record,'Node 6')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 6 is a great choice! Base on algorithm's calculation, the next best demo point will be Node 10 to reach HIGHEST overall scores, second best demo point would be Node 11 ^^ GL! "; 
            
            elseif (strcmp(app.record,'Node 7')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 7 is a amazing move in first demo, the next best demo point will be Node 6/Node 10 to reach HIGHEST overall scores, second best demo point would be Node 8 ^^ GL! "; 
            
            elseif (strcmp(app.record,'Node 8')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 8 is a great choice! Base on algorithm's calculation, the next best demo point will be Node 11/Node 7 to reach HIGHEST overall scores, second best demo point would be Node 5/Node 10^^ GL! "; 
            
            elseif (strcmp(app.record,'Node 9')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 9 is a great choice! Base on algorithm's calculation, the next best demo point will be Node 10/Node 11 to reach HIGHEST overall scores, second best demo point would be Node 7 ^^ GL! "; 
            
            elseif (strcmp(app.record,'Node 10')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 10 is a great choice! Base on algorithm's calculation, the next best demo point will be Node 2/Node 4 to reach HIGHEST overall scores, second best demo point would be Node 6 ^^ GL! "; 
            
            elseif (strcmp(app.record,'Node 11')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 11 is a great choice! Base on algorithm's calculation, the next best demo point will be Node 9/Node 10 to reach HIGHEST overall scores, second best demo point would be Node 1/Node 7^^ GL! "; 
            
            elseif (strcmp(app.record,'Node 12')==1)
                
                app.AdviceTextArea.Value = "Very well done, Node 12 is a great choice! Base on algorithm's calculation, the next best demo point will be Node 7 to reach HIGHEST overall scores, there are no very good second demo best point ^^ GL! "; 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 1 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            elseif (strcmp(app.record,'Node 1Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 1Node 2')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 1Node 3')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 1Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 1Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7')==1)
                
                app.AdviceTextArea.Value = "Thanks for taking my advice! Base on my calculation next demo point 7 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 1Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 1Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 1Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 8 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 1Node 11')==1)
                
                app.AdviceTextArea.Value = "Thanks for taking my advice! Base on my calculation next demo point 7 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 1Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 2 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
            elseif (strcmp(app.record,'Node 2Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 2Node 2')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 2Node 3')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 2Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 2Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 11 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 2Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 2Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 2Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 11 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 2Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 2Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 3 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 3Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 3Node 2')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 3Node 3')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 3Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 3Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 10 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 3Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 3Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 3Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 11 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 3Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 3Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 4 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 4Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 11 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 4Node 2')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 4Node 3')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 4Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 11 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 4Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 4Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 4Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 4 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 4Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 4Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 4Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 2 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 4Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 11 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 4Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 5 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 5Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 11 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 5Node 2')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 5Node 3')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 5Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 5Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 5Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 5Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 11 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 5Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 5Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 5Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 8 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 5Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 5Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 6 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 6Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 6Node 2')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 6Node 3')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 6Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 6Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 6Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 6Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 11 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 6Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 6Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 6Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 11 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 6Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 6Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 7 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 7Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 11 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 7Node 2')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 7 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 7Node 3')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 7Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 4 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 7Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 11 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 7Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 7Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 12 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 7Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 11 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 7Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 7Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 6 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 7Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 8 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 7Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will boost average and reach a generally good teaching effect! "; 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 8 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 8Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 8Node 2')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 8Node 3')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 8Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 11 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 8Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 8Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 8Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 11 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 8Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 8Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 8Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 5 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 8Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 7 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 8Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 9 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 9Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 9Node 2')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 9Node 3')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 9Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 9Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 9Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 9Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 9Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 9Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 9Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 11 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 9Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 10 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 9Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 10 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 10Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 12 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 2')==1)
                
                app.AdviceTextArea.Value = "Nice move! Thanks for taking my advice! Base on my calculation next demo point 4 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 10Node 3')==1)
                
                app.AdviceTextArea.Value = "Nice move! Don't worry, base on my calculation next demo point 4 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 2 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 10Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 8 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 10Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 10Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 5 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 3 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 3 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 4 will boost average and reach a generally good teaching effect! "; 
                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 10 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 10Node 1')==1)
                
                app.AdviceTextArea.Value = "Oops, this isn't a good choice from my understanding but nice try! Don't worry, base on my calculation next demo point 12 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 2')==1)
                
                app.AdviceTextArea.Value = "Nice move! Thanks for taking my advice! Base on my calculation next demo point 4 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 10Node 3')==1)
                
                app.AdviceTextArea.Value = "Nice move! Don't worry, base on my calculation next demo point 4 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 2 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 10Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 8 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 10Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 10Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 5 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 3 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 3 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 10Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 4 will boost average and reach a generally good teaching effect! "; 
                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 11 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 11Node 1')==1)
                
                app.AdviceTextArea.Value = "Well done! Base on my calculation next demo point 7 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 11Node 2')==1)
                
                app.AdviceTextArea.Value = "Nice move! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 11Node 3')==1)
                
                app.AdviceTextArea.Value = "Well done! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 11Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 11 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 11Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 11Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 11Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 12 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 11Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 11Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 11Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 9 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 11Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 4 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 11Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will boost average and reach a generally good teaching effect! "; 
                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Second demo advice Node 12 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 12Node 1')==1)
                
                app.AdviceTextArea.Value = "Well done! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 12Node 2')==1)
                
                app.AdviceTextArea.Value = "Nice move! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 12Node 3')==1)
                
                app.AdviceTextArea.Value = "Well done! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 12Node 4')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
                
            elseif (strcmp(app.record,'Node 12Node 5')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 12Node 6')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
            
            elseif (strcmp(app.record,'Node 12Node 7')==1)
                
                app.AdviceTextArea.Value = "Good job! Thanks for taking my advice! Base on my calculation next demo point 7 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 12Node 8')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 12Node 9')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 12Node 10')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 4 will reach the BEST teaching effect! "; 

            elseif (strcmp(app.record,'Node 12Node 11')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 7 will reach a generally good teaching effect! "; 

            elseif (strcmp(app.record,'Node 12Node 12')==1)
                
                app.AdviceTextArea.Value = "Good job! Base on my calculation next demo point 10 will boost average and reach a generally good teaching effect! "; 
                

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 1Node 1Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 1')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
            elseif (strcmp(app.record,'Node 1Node 1Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 2')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 2')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 3')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 3')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 4')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 4')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 5')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 5')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 6')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 6')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 7')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 7')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 8')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 8')==1) 
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 9')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 9')==1)  
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 10')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 10')==1)  
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 11')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 11')==1)                  
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 1Node 1Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 2Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 3Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 4Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 5Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 6Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 7Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 1Node 8Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 9Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 1Node 10Node 12')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 1Node 11Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 1Node 12Node 12')==1) 
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 2Node 1Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            

            elseif (strcmp(app.record,'Node 2Node 7Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 1')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 1')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
            elseif (strcmp(app.record,'Node 2Node 1Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 2')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 2')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 3')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 3')==1)

                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 4')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 4')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 5')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 5')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 6')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 6')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 7')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 7')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 8')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 8')==1) 
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 9')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 9')==1)  
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 10')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 10')==1)  
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 11')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 11')==1)                  
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 2Node 1Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 2Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 3Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 4Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 5Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 6Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 7Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 2Node 8Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 9Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 2Node 10Node 12')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 2Node 11Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 2Node 12Node 12')==1)                  
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 3Node 1Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 1')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 1')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
            elseif (strcmp(app.record,'Node 3Node 1Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 2')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 2')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 3')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 3')==1)

                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 4')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 4')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 5')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 5')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 6')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 6')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 7')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 7')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 7')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 8')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 8')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 8')==1) 
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 9')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 9')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 9')==1)  
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 10')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 10')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 12Node 10')==1)  
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 11')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 11')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 3Node 12Node 11')==1)  
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
                
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 3Node 1Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 2Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 3Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 4Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 5Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 6Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 7Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 3Node 8Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 9Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 10Node 12')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 3Node 11Node 12')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 3Node 12Node 12')==1) 
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Third demo, end of experiment %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            elseif (strcmp(app.record,'Node 4Node 1Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 2Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 3Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 4Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 5Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 6Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 7Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 8Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 9Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 10Node 1')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 4Node 11Node 1')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 4Node 12Node 1')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
            elseif (strcmp(app.record,'Node 4Node 1Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 2Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 3Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 4Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 5Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 6Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 7Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 8Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 9Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 10Node 2')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 4Node 11Node 2')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 4Node 12Node 2')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 4Node 1Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 2Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 3Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 4Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 5Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 6Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 7Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 8Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 9Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 10Node 3')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 4Node 11Node 3')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 4Node 12Node 3')==1)

                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 4Node 1Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 2Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 3Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 4Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 5Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 6Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 7Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 8Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 9Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 10Node 4')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 4Node 11Node 4')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 4Node 12Node 4')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 4Node 1Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 2Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 3Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 4Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 5Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 6Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 7Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                                
            elseif (strcmp(app.record,'Node 4Node 8Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 9Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 10Node 5')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
                           
            elseif (strcmp(app.record,'Node 4Node 11Node 5')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            elseif (strcmp(app.record,'Node 4Node 12Node 5')==1)
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

            elseif (strcmp(app.record,'Node 4Node 1Node 6')==1)
                
                app.AdviceTextArea.Value = "Thank you for participating this experiment, all data has been recorded under UK General Data Protection Regulation, have a good day! "; 
            
            elseif (strcmp(app.record,'Node 4Node 2Node 6')==1)
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